classdef faultZ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ExecutionTimesTextArea         matlab.ui.control.TextArea
        ExecutionTimesTextAreaLabel    matlab.ui.control.Label
        TimeTextArea                   matlab.ui.control.TextArea
        TimeTextAreaLabel              matlab.ui.control.Label
        ControlsPanel                  matlab.ui.container.Panel
        LbkmLabel                      matlab.ui.control.Label
        hkmLabel                       matlab.ui.control.Label
        RestartButton                  matlab.ui.control.Button
        NameEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        ExportButton                   matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        GridPointsEditField            matlab.ui.control.NumericEditField
        GridPointsEditFieldLabel       matlab.ui.control.Label
        SetPropertiesButton            matlab.ui.control.Button
        OptionsPanel                   matlab.ui.container.Panel
        LoadingRatemsEditField         matlab.ui.control.NumericEditField
        LoadingRatemsEditFieldLabel    matlab.ui.control.Label
        RunTimeyrEditField             matlab.ui.control.NumericEditField
        RunTimeyrEditFieldLabel        matlab.ui.control.Label
        PlotsOnCheckBox                matlab.ui.control.CheckBox
        StateLawDropDown               matlab.ui.control.DropDown
        StateLawDropDownLabel          matlab.ui.control.Label
        FrictionParametersPanel        matlab.ui.container.Panel
        mu0EditField                   matlab.ui.control.NumericEditField
        mu0EditFieldLabel              matlab.ui.control.Label
        v0EditField                    matlab.ui.control.NumericEditField
        v0EditFieldLabel               matlab.ui.control.Label
        dcEditField                    matlab.ui.control.NumericEditField
        dcEditFieldLabel               matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        ElasticParametersPanel         matlab.ui.container.Panel
        NormalStressMPaEditField       matlab.ui.control.NumericEditField
        NormalStressMPaEditFieldLabel  matlab.ui.control.Label
        PoissonRatioEditField          matlab.ui.control.NumericEditField
        PoissonRatioEditFieldLabel     matlab.ui.control.Label
        ShearModulusGPaEditField       matlab.ui.control.NumericEditField
        ShearModulusGPaEditFieldLabel  matlab.ui.control.Label
        GeometryPanel                  matlab.ui.container.Panel
        GeometryDropDown               matlab.ui.control.DropDown
        GeometryDropDownLabel          matlab.ui.control.Label
        PlotGeometryButton             matlab.ui.control.Button
        BurialDepthkmEditField         matlab.ui.control.NumericEditField
        BurialDepthkmEditFieldLabel    matlab.ui.control.Label
        FaultLengthkmEditField         matlab.ui.control.NumericEditField
        FaultLengthkmEditFieldLabel    matlab.ui.control.Label
        DipAngleEditField              matlab.ui.control.NumericEditField
        DipAngleEditFieldLabel         matlab.ui.control.Label
        SurfaceSlopeEditField          matlab.ui.control.NumericEditField
        SurfaceSlopeEditFieldLabel     matlab.ui.control.Label
        VelAxes                        matlab.ui.control.UIAxes
        GeoAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        p % structure containing all properties and geometric information.
        STOP % flag for stopping a simulation.
        Out % stucture containing output from a completed simulation.
    end
    
    methods (Access = private)
        
        function DippingFaultSetUpUI(app)
        
        %%% Get parameter values from fields.
            
        %%% Run time of the simulation.            
            RunTime = app.RunTimeyrEditField.Value;
            time = [0 RunTime];
            
        %%% Frictional parameters
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            d_c = app.dcEditField.Value;
            v_ref = app.v0EditField.Value;
            mu_0 = app.mu0EditField.Value;

            v_plate = app.LoadingRatemsEditField.Value;

        %%% Elastic parameters
            v_shear = 3464;                         % shear wave speed [m / s]
            EffStress_0 = app.NormalStressMPaEditField.Value;                
            
            
            switch(app.GeometryDropDown.Value)
                case 'Full Space'
                    Poisson_1 = app.PoissonRatioEditField.Value;
                    ShearMod_1 = 1e3*app.ShearModulusGPaEditField.Value;
                    ShearMod_2 = ShearMod_1;
                    Poisson_2 = Poisson_1;
                    
                    Beta = -(ShearMod_2*(1 - 2*Poisson_1) - ShearMod_1*(1 - 2*Poisson_2))...
                        ./(2*(ShearMod_2*(1 - Poisson_1) + ShearMod_1*(1 - Poisson_2)));
                    
                    M = (2*ShearMod_1*ShearMod_2/(1 - Beta^2))/((ShearMod_2*(1 - Poisson_1)...
                        + ShearMod_1*(1 - Poisson_2)));
                    eta = ShearMod_1/(2*v_shear);             % radiation damping [MPa s / m]
                    
        %%% Length scales.    
                    H_s = pi*M*d_c/(EffStress_0*(b-a)); 
                    L_b = d_c*M/(b*EffStress_0);
                    L_c = 4*pi*d_c*M/(EffStress_0*a*mu_0^2);
                
        %%% Set up the numerical grid.
                    FaultLength = 1e3*app.FaultLengthkmEditField.Value;
                    dXi = L_b/40;
                    N_40 = round(FaultLength/dXi);
                    n2 = nextpow2(N_40);
                    if 2^n2 < 0.9*N_40
                        N = 2^(n2 + 1);
                    else
                        N = 2^n2;
                    end
                    N_tot = 3*N;
                    dx = FaultLength/(N - 1);
                    TotalLength = N_tot*dx;
                    %TotalLength = FaultLength + 2*PadLength;
                    Xi = (0 : dx : FaultLength)' - FaultLength/2;
                    X_tot = (0 : dx : TotalLength)' - floor(N_tot/(2*N))*N*dx;
                    N_pad = (N_tot - N)/2;
                    
        %%% Computate the wave number.
                    WaveNumber = fftshift(2*pi.*abs((-N_tot/2:N_tot/2-1)')./(TotalLength-dx));
                    
                case 'Half Space'
                    Poisson = app.PoissonRatioEditField.Value;
                    ShearMod = 1e3*app.ShearModulusGPaEditField.Value;
                    eta = ShearMod/(2*v_shear);             % radiation damping [MPa s / m]
                    
        %%% Length scales.    
                    H_s = pi*ShearMod*d_c/(EffStress_0*(1 - Poisson)*(b-a)); 
                    L_b = d_c*ShearMod/(b*EffStress_0*(1 - Poisson));
                    L_c = 4*pi*d_c*ShearMod/(EffStress_0*(1 - Poisson)*a*mu_0^2);
                    
        %%% Set up grid: xi is the along-fault coordinate, x is the horizontal
        %%% coordinate, z is the vertical coordinate. Grid is defined on segment 
        %%% centers.                
                    FaultLength = 1e3*app.FaultLengthkmEditField.Value;
                    dXi = L_b/40;
                    N = round(FaultLength/dXi);
                    Xi = 0*dXi + (dXi/2: dXi: N*dXi - dXi/2)';
    
        %%% Wedge geometry.
                    alpha = app.SurfaceSlopeEditField.Value;
                    beta = app.DipAngleEditField.Value;
        %%% Zed is the fault's depth below the upper surface.
                    Zed = Xi*(sin(pi*beta/180) + cos(pi*beta/180)*tan(pi*alpha/180));
        %%% Horizontal location.
                    X = Xi*cos(pi*beta/180);               % fault depth [m]
                    
        %%% Compute the elastic kernels for the defined grid.
                    K_s = hodlr('handle',...
                      @(i,j) WedgeKernel1b_HODLR(i, j, Xi', beta, alpha, 1), N, N); 
                    K_n = hodlr('handle',...
                     @(i,j) WedgeKernel1b_HODLR(i, j, Xi', beta, alpha, 0), N, N);
    %                 K_s = full(K_s);
    %                 K_n = full(K_n);
            end
                           

        %%% Loading rate.
            Loading = 0;            
            % Loading = (v_plate/pi)*(pi/2 ...
            %     + asin((FaultLength/2 - (Xi - Xi(1)))/(FaultLength/2))) +1e-3*v_plate;
            
        %%% Initial conditions at plate rate steady state.
        %%% Initial slip velocity.
            v_0 = v_plate*ones(size(Xi));
            %v_0 = v_plate*(rand(N,1) + 0.5);            
        %%% Initial state.
            state_0 = 1e-1*(d_c./v_plate)*ones(size(Xi));
            % state_0 = (d_c/v_plate)*(rand(N,1) + 0.5);
            % Np = round(N/2);
            % state_0(end-Np:end) = (d_c/v_plate)*(rand(Np+1,1) + 0.5);
        %%% Initial normal stress.
            sigma_0 = EffStress_0*ones(size(Xi));
        %%% Initial shear stress.
            tau_0 = mu_0*sigma_0;
            
        %%% Add load
            %v_0 = v_0 + v_load;
            Vars_0 = [v_0; tau_0; state_0; sigma_0];
            
%         %%% Parameters structure containing all necessary information to run a
%         %%% simulation.
%             p = struct('Geometry', [], 'Material', [], 'Friction', [],...
%                 'Options', []);
        %%% Friction parameters.
            app.p.Friction = struct('a', a, 'b', b, 'd_c', d_c, 'v_ref', v_ref,...
                'mu_ref', mu_0, 'H_star', H_s, 'L_b', L_b, 'L_c', L_c);                    
        %%% Options.
            plots = true;
            app.p.Options = struct('StateLaw', app.StateLawDropDown.Value,...
                'Geometry', app.GeometryDropDown.Value, 'Restart', 0,...
                'Plots', plots);
        
        %%% Different parameters for the different geometries.
            switch(app.GeometryDropDown.Value)
                case 'Full Space'
        %%% Geometrical and numerical parameters.    
                    app.p.Geometry = struct('FaultLength', FaultLength,...
                        'FaultCoordinates', Xi, 'GridPoints', N,...
                        'GridSpacing', dXi, 'Padding', N_pad, 'WaveNumber', WaveNumber,...
                        'Runtime', time, 'Loading', Loading, 'InitConditions', Vars_0);
        %%% Material parameters.
                    app.p.Material = struct('ElasticModulus', M, 'BiMatContrast', Beta,...
                        'BackgroundStress', EffStress_0, 'PlateRate', v_plate, 'ShearWaveSpeed',...
                        v_shear, 'RadiationDamping', eta);
                    
                case 'Half Space'            
        %%% Geometrical and numerical parameters.    
                    app.p.Geometry = struct('SurfaceSlope', alpha, 'FaultDip', beta, 'FaultLength',...
                        FaultLength, 'FaultCoordinates', Xi, 'GridPoints', N, 'GridSpacing',...
                        dXi, 'Runtime', time, 'Loading', Loading, 'InitConditions', Vars_0,...
                        'ShearKernel', K_s, 'NormalKernel', K_n, 'Depth', Zed,...
                        'Distance', X);            
        %%% Material parameters.
                    app.p.Material = struct('ShearModulus', ShearMod, 'PoissonRatio', Poisson,...
                        'BackgroundStress', EffStress_0, 'PlateRate', v_plate, 'ShearWaveSpeed',...
                        v_shear, 'RadiationDamping', eta);
            end
            
        end % DippingFaultSetUpUI
        
        function GetParameters(app)
        %%% This function gets parameter values from numeric edit fields
        %%% It is called by the Start button. The start button should be 
        %%% deactivated if a grid doesn't exist, in which case p will exist 
        %%% as well. Therefore, this fuction just updates parameters/values
        %%% in p that don't require a new grid?

        %%% Since the initial conditions depend on the grid, these get set
        %%% whenever the grid is generated.
            
        %%% Run time of the simulation.            
            RunTime = app.RunTimeyrEditField.Value;
            time = [0 RunTime];
            
        %%% Frictional parameters
            a = app.aEditField.Value;
            v_ref = app.v0EditField.Value;
            mu_0 = app.mu0EditField.Value;            
                          
        %%% Loading rate.
            v_plate = app.LoadingRatemsEditField.Value;
            Loading = 0;            
            % Loading = (v_plate/pi)*(pi/2  + asin((FaultLength/2...
            % - (Xi - Xi(1)))/(FaultLength/2))) +1e-3*v_plate;

        %%% Update the parameters structure.
            app.p.Geometry.Runtime = time;            
            app.p.Geometry.Loading = Loading;
            
        %%% Material parameters.
            app.p.Material.PlateRate = v_plate;
            
        %%% Friction parameters.
            app.p.Friction.a = a;
            app.p.Friction.mu_ref = mu_0;
            app.p.Friction.v_ref = v_ref;
            
        %%% Options.
            app.p.Options.StateLaw = app.StateLawDropDown.Value;
            
        end % GetParameters


        function Out = RunFaultSimUI(app)
        %%% For claritry and ease of use, this code is written in 
        %%% dimensional form.
            
        %%% Geometrical and numerical parameters.
            N = app.p.Geometry.GridPoints;
            spy = 24*365.25*3600;
            time = spy*app.p.Geometry.Runtime;            
            Loading = app.p.Geometry.Loading;
            
        %%% Material parameters.
            v_plate = app.p.Material.PlateRate;
            eta = app.p.Material.RadiationDamping;                     
            
        %%% Friction parameters.
            a = app.p.Friction.a;
            b = app.p.Friction.b;
            d_c = app.p.Friction.d_c;
            mu_ref = app.p.Friction.mu_ref;
            v_ref = app.p.Friction.v_ref;

        %%% Initial conditions.
            if app.p.Options.Restart == 0
                Vars_0 = app.p.Geometry.InitConditions;
            else
                Vars_0 = app.Out.Final;
            end

        %%% Options.
            StateCase = app.p.Options.StateLaw;
            GeoCase = app.p.Options.Geometry;

            options = odeset('OutputFcn', @PlotVel, 'Events', @Stop, 'RelTol', 1e-4, 'AbsTol', 1e-6);
            switch(GeoCase)
                case 'Full Space'
                    X = app.p.Geometry.FaultCoordinates;
                    M = app.p.Material.ElasticModulus;
                    Beta = app.p.Material.BiMatContrast;
                    WaveNumber = app.p.Geometry.WaveNumber;
                    N_pad = app.p.Geometry.Padding;
                    ode45(@FullSpaceFault, time, Vars_0, options, X, N);
                case 'Half Space'
                    X = app.p.Geometry.Distance;
                    ShearMod = app.p.Material.ShearModulus;
                    Poisson = app.p.Material.PoissonRatio;
                    K_s = (ShearMod/(2*pi*(1 - Poisson)))*app.p.Geometry.ShearKernel;
                    K_n = (ShearMod/(2*pi*(1 - Poisson)))*app.p.Geometry.NormalKernel;
                    %K_n = 0;
                    ode45(@DippingFault, time, Vars_0, options, X, N);
            end
            
%             O = ones(N,N);
%             Z = zeros(N,N);
%             I = eye(N);
%             
%             Jac_v = [O, Z, I, I];
%             Jac_tau = [O, Z, Z, Z];
%             Jac_state = [I, Z, I, Z];
%             Jac_sigma = Jac_tau;
%             
%             S = sparse([Jac_v; Jac_tau; Jac_state; Jac_sigma]);                          
            %%% Solve the equations.
%            if nucleation
%                options = odeset('OutputFcn', @PlotVel, 'Events', @Stop);%, 'Vectorized', 'on');%, 'JPattern', S);%, ...
%                    'RelTol', 1e-7, 'AbsTol', 1e-7);
%            else
%                options = odeset('OutputFcn', @PlotVel, 'Events', @Stop, 'RelTol', 1e-4, 'AbsTol', 1e-6);
%            end
%            ode45(@DippingFault, time, Vars_0, options, X, N);                          

            Out = struct(...
                'NormalStress', SaveStreamData('Read', 'Sigma.dat')',...
                'ShearStress', SaveStreamData('Read', 'Tau.dat')',...         
                'State', SaveStreamData('Read', 'State.dat')',...
                'Vel', SaveStreamData('Read', 'Vel.dat')',...
                'Time', SaveStreamData('Read', 'Time.dat')');
        %%% Calculate slip.
            Slip = [zeros(1, N); cumsum(repmat(diff(Out.Time),1,N).*Out.Vel(2:end,:))];
            Out.Slip = Slip;
            
        %%% Final conditions.
            Out.Final = [Out.Vel(end,:)'; Out.ShearStress(end,:)'; Out.State(end,:)';...
                Out.NormalStress(end,:)'];
                
        %%% Clean up the .dat files.
            delete Sigma.dat Tau.dat Time.dat Vel.dat State.dat
            
%%%---------------------------------------------------------------------%%%
%%% Governing equaitions for a full-space fault.
            function dVarsdt = FullSpaceFault(t, Vars, X, N)
                vel = Vars(1:N);
%                 tau = Vars(N+1:2*N);
                state = Vars(2*N+1:3*N);
                sigma = Vars(3*N+1:4*N);
            
            %%% State evolution.
                switch(StateCase)
                    case 'Aging'
                        dstatedt = 1 - vel.*state./d_c;
                    case 'Slip'
                        dstatedt = -(vel.*state./d_c).*log(vel.*state./d_c);
                end
            %%% Shear stress
                Vel2 = [v_plate*ones(N_pad,1); vel; v_plate*ones(N_pad,1)];
                Ve2_fft = fft(Vel2);
                Stress_fft = WaveNumber.*Ve2_fft;
                ElasticStress = ifft(Stress_fft);
                dtaudt = -(M/2)*ElasticStress(N_pad + 1: N_pad + N);
            
            %%% Normal stress
            %    NormFull_fft = 1i*WaveNumber2.*Ve2_fft;
            %    dsigmadt = real(fftshift(ifft(ifftshift(NormFull_fft))));
            %    dsigmadt = -(M*Beta/2)*dsigmadt(N_pad + 1 : N_pad + N);
            %    dsigmadt = -(M*Beta/2)*D1*Vel;
                dsigmadt = -(M*Beta/2)*zeros(N,1);
            
            %%% Velocity equation.
                g = exp((mu_ref + b.*log(v_ref.*state./d_c))./a);
                u = (vel/(2*v_ref)).*g;
                f = asinh(u);
                dgdt = (b.*g./(a.*state)).*dstatedt;
                dvdt = (g + (2*v_ref*eta./(a.*sigma))...
                    .*(1 + u.^2).^(1/2)).^(-1).*((2*v_ref./(a.*sigma))...
                    .*(1 + u.^2).^(1/2).*(Loading + dtaudt - a.*f.*dsigmadt) - vel.*dgdt);
                
            %%% Combine evolution equations.
                dVarsdt = [dvdt; dtaudt; dstatedt; dsigmadt];
            end % FullSpaceFault

%%%---------------------------------------------------------------------%%%
%%% Governing equations for a dipping fault.            
            function dVarsdt = DippingFault(t, Vars, X, N)
                vel = Vars(1:N);
%                 tau = Vars(N+1:2*N);
                state = Vars(2*N+1:3*N);
                sigma = Vars(3*N+1:4*N);
            
            %%% Shear stress equation.
                dtaudt = K_s*(vel - v_plate);
            %%% Normal stress equation.
                dsigmadt = K_n*(vel - v_plate);
            %%% State evolution. Some concern that this may slow down the
            %%% code, since this switch gets checked every timestep.
            %%% However, it seems about the same for ~40s runs.
                switch(StateCase)
                    case 'Aging'
                        dstatedt = 1 - vel.*state./d_c;
                    case 'Slip'
                        dstatedt = -(vel.*state./d_c).*log(vel.*state./d_c);
                end            
            %%% Velocity equation.
                g = exp((mu_ref + b.*log(v_ref.*state./d_c))./a);
                u = (vel/(2*v_ref)).*g;
                f = asinh(u);
                dgdt = (b.*g./(a.*state)).*dstatedt;
                dvdt = (g + (2*v_ref*eta./(a.*sigma))...
                    .*(1 + u.^2).^(1/2)).^(-1).*((2*v_ref./(a.*sigma))...
                    .*(1 + u.^2).^(1/2).*(Loading + dtaudt - a.*f.*dsigmadt) - vel.*dgdt);
                
            %%% Combine evolution equations.
                dVarsdt = [dvdt; dtaudt; dstatedt; dsigmadt];
            end %DippingFault
            
%%%---------------------------------------------------------------------%%%
%%% ode events function. Stops the simulation when the "stop" button is
%%% pushed. Includes if statement for stopping simulations under nucleation
%%% condition.
            function [position, isterminal, direction] = Stop(t, Vars, X, N)
                               
%                 if app.NucleationCheckBox.Value
%                     if app.STOP == 1
%                         vel = Vars(1:N);
%                         v_max = max(vel);
%                         detect = v_max < 0.01;
%                         position = double(detect);
%                     else
%                         position = app.STOP;
%                     end
%                 else                    
%                     position = app.STOP;
%                 end
                position = app.STOP;
                isterminal = 1;
                direction = 0;
                
            end %Stop

%%%---------------------------------------------------------------------%%%
%%% ode output function. Dumps simulation data into .dat files while
%%% simulations are running. When simulation ends, .dat files are imported
%%% into matlab and deleted (this occurs in the main body of RunFaultSimUI.
%%% This function also plots live simulation data.
            function status = PlotVel(time, Vars, flag, X, N)
                
                %%% 1. Target_Fig not needed?
                  
                persistent gs timerval %v_line %f v x 
                status = 0;
                
                switch(flag)
                    case 'init'
                %%% Initialize the plotting figure. 
                        if app.PlotsOnCheckBox.Value
                            hold(app.GeoAxes, 'on')
%                            set(app.VelAxes, 'YScale', 'log')
%                            xlim(app.VelAxes, [X(1) X(end)]/1e3)
%                            ylim(app.VelAxes, [1e-15 1e0])
                            
%                             v_line = semilogy(app.VelAxes, X/1e3, Vars(1:N), 'r', 'LineWidth', 2);
%                             xlim(app.VelAxes, [0 X(end)]/1e3)
%                             ylim(app.VelAxes, [1e-15 1e0])
                            %figure;
                        %    Target_Axis = app.VelAxes;
                %             v = Vars(1:N);
                %             x = X/1e3;
                %             f = semilogy(Target_Axis, x, v, 'r', 'LineWidth', 2);
                %             ylim([1e-25 1e2])
                %             f.XDataSource = 'x';
                %             f.YDataSource = 'v';
                        end
                        
                %%% Initialize the .dat files.
                        Vn = 'Vel.dat';
                        Tn = 'Time.dat';
                        Tan = 'Tau.dat';
                        Sig_n = 'Sigma.dat';
                        Stn = 'State.dat';
                        gs.Vel = SaveStreamData('Init',Vn);
                        gs.Time = SaveStreamData('Init',Tn);
                        gs.Tau = SaveStreamData('Init',Tan);
                        gs.Sigma = SaveStreamData('Init',Sig_n);
                        gs.State = SaveStreamData('Init', Stn);
                        
                % %%% Write to the .dat files for the initial conditions.
                        SaveStreamData('Write', gs.Vel, Vars(1:N, 1));
                        SaveStreamData('Write', gs.Time, time(1));
                        SaveStreamData('Write', gs.State, Vars(2*N+1 : 3*N, 1));
                        SaveStreamData('Write', gs.Tau, Vars(N+1 : 2*N, 1));
                        SaveStreamData('Write', gs.Sigma, Vars(3*N+1 : 4*N, 1));
                
                %%% Initialize the in-simulation time display.
                        timerval = tic;                        
%                         spy = 365.25*24*3600;
%                         r = mod(time(1), spy);
%                         years = (time(1) - r)/spy;
%                         days = r/(24*3600);
%                         fprintf(1,'Time: %8d years, %6.2f days \n', [years, days]);
                        
                    case ''
%%% Display the in-simulation time. Do this without drawnow.
%                        spy = 365.25*24*3600;
                        r = mod(time(1), spy);
                        years = (time(1) - r)/spy;
                        days = r/(24*3600);
                        caption = sprintf('%8d years, %6.2f days', [years, days]);
                        app.TimeTextArea.Value = caption;
                        extime = sprintf('%6.2f', toc);
                        app.ExecutionTimesTextArea.Value = extime;
%                        drawnow limitrate
%             fprintf(1,'Run time: %d years, %6.2f days \n', [years, days]);

%%% This is some wonky code to slow down the drawnow command even mre than
%%% the limitrate option does. For no plots, seeing 11% decrease. For plots
%%% on, seeing 25% decrease.
                        time2 = toc(timerval);
                        if time2 > 0.1
                            drawnow;
                            timerval = tic;
                        end
                        
                %%% Plot the logarithm of the slip velocity.
                        if app.PlotsOnCheckBox.Value
                            
                %             x = X/1e3;
                %             v = Vars(1:N);
                %             refreshdata(Target_Axis)i_blue = Vars(1:N) > 1e-2;
%                             semilogy(app.VelAxes, X/1e3, Vars(1:N), 'r', 'LineWidth', 2);
                            cla(app.VelAxes) % cla: clears the axes.
                            line(app.VelAxes, X/1e3, Vars(1:N), 'Color', 'r', 'LineWidth', 2);
                            
%                             xlim(app.VelAxes, [X(1) X(end)]/1e3)
%                             ylim(app.VelAxes, [1e-15 1e0])
%                             drawnow
                            
%                             x = X/1e3;
%                             y = Vars(1:N);                                             
%                             set(v_line,'XData',x,'YData',y);

                %%% Plot blue fast speeds on GeoAxes. Code has problems
                %%% when there are multiple blue patches that aren't
                %%% contiguous.
                            switch(GeoCase)
                                case 'Half Space'
                                    children = get(app.GeoAxes, 'children');
                                    if numel(children) > 3
                                     delete(children(1));
                                    end
                                    i_blue = Vars(1:N) > 1e-2;
                                    Z = X*tan(app.p.Geometry.FaultDip*pi/180);
                                    plot(app.GeoAxes, 1e-3*X(i_blue), 1e-3*Z(i_blue), 'b', 'LineWidth', 2)
                            end
%                             plot(app.GeoAxes, 1e-3*X(i_blue), zeros(sum(i_blue),1), 'b', 'LineWidth', 2)
                        end


                %%% Write to the .dat files during simulation         
                        SaveStreamData('Write', gs.Vel, Vars(1:N, 1));
                        SaveStreamData('Write', gs.Time, time(1));
                        SaveStreamData('Write', gs.State, Vars(2*N+1 : 3*N, 1));
                        SaveStreamData('Write', gs.Tau, Vars(N+1 : 2*N, 1));
                        SaveStreamData('Write', gs.Sigma, Vars(3*N+1 : 4*N, 1));
                        
                    case 'done'
                %%% Finalize the .dat files.
                        SaveStreamData('Finalize', gs.Vel);
                        SaveStreamData('Finalize', gs.Time);
                        SaveStreamData('Finalize', gs.Tau);
                        SaveStreamData('Finalize', gs.Sigma);
                        SaveStreamData('Finalize', gs.State);
                        hold(app.GeoAxes, 'off')
                        
                end % switch
            end % PlotVel
        end % RunFaultSimUI        
        
        function DisableControls(app)
        %%% Disable Start, Stop, and Restart buttons.
            app.StartButton.Enable = "off";
            app.StopButton.Enable = "off";
            app.RestartButton.Enable = "off";
        end
    end % methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %%% Parameters structure containing all necessary information to run a
        %%% simulation.
            app.p = struct('Geometry', [], 'Material', [], 'Friction', [],...
                'Options', []);
        %%% Set the stop flag to 1, which means "don't stop".
            app.STOP = 1;
        %%% Populate the UI with values.
        %%% Geometry
            app.GeometryDropDown.Value = 'Half Space';
            app.SurfaceSlopeEditField.Value = 0;
            app.DipAngleEditField.Value = 10;
            app.BurialDepthkmEditField.Value = 0;
            app.FaultLengthkmEditField.Value = 50;
        %%% Elastic
            app.ShearModulusGPaEditField.Value = 30;
            app.PoissonRatioEditField.Value = 0.25;
            app.NormalStressMPaEditField.Value = 5;
        %%% Friction
            app.aEditField.Value = 0.007;
            app.bEditField.Value = 0.01;
            app.dcEditField.Value = 0.01;
            app.v0EditField.Value = 1e-9;
            app.mu0EditField.Value = 0.3;
        %%% Simulation
            app.LoadingRatemsEditField.Value = 1e-9;
            app.RunTimeyrEditField.Value = 200;
        %%% Export data structure name.
            app.NameEditField.Value = 'SimData';
        %%% Disable Start, Stop, and Restart buttons.
            DisableControls(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            GetParameters(app);
            app.STOP = 1;
            app.p.Options.Restart = 0;
            tic;
            app.Out = RunFaultSimUI(app);
            %toc; % uncomment this to print execution time to cmd window.
        end

        % Button pushed function: SetPropertiesButton
        function SetPropertiesButtonPushed(app, event)
        %%% import the properties from the edit fields into the parameters
        %%% structure.
            DippingFaultSetUpUI(app);
        %%% Display the number of grid points, and RSF length scales.
            app.GridPointsEditField.Value = app.p.Geometry.GridPoints;
            app.hkmLabel.Text = sprintf('h* = %10.3f [km]',...
                app.p.Friction.H_star/1e3);
            app.LbkmLabel.Text = sprintf('Lb = %10.3f [km]',...
                app.p.Friction.L_b/1e3);
        %%% Disable SetProperties button.
            app.SetPropertiesButton.Enable = "off";
        %%% Enable Start, Stop, and Restart buttons.
            app.StartButton.Enable = "on";
            app.StopButton.Enable = "on";
            app.RestartButton.Enable = "on";            
        end

        % Button pushed function: PlotGeometryButton
        function PlotGeometryButtonPushed(app, event)
        %%% Maintain proper alignment for VelAxes and GeoAxes. This should
        %%% only need to be called once, so should properly be in the
        %%% startupFcn, but for some reason doesn't do anything there.
            app.VelAxes.PositionConstraint = 'innerposition';
            app.GeoAxes.PositionConstraint = 'innerposition';
            
            switch(app.GeometryDropDown.Value)
                case 'Full Space'
                    FaultLength = app.FaultLengthkmEditField.Value;
                    x_max = FaultLength/2;
                    x_min = -FaultLength/2; 
                    area(app.GeoAxes, [x_min,x_max], [x_max,x_max], 'FaceColor', '#BEBEBE')
                    hold(app.GeoAxes, 'on');
                    area(app.GeoAxes, [x_min,x_max], [x_min,x_min], 'FaceColor', '#BEBEBE')
                    plot(app.GeoAxes, [x_min; x_max], [0; 0], 'r', 'LineWidth', 2)
                    xlim(app.GeoAxes, [x_min x_max])
                    ylim(app.GeoAxes, [x_min x_max])
                    hold(app.GeoAxes, 'off')
                %%% Set VelAxes limits to speed up drawnow calls.
                    set(app.VelAxes, 'YScale', 'log')
                    xlim(app.VelAxes, [x_min x_max])
                    ylim(app.VelAxes, [1e-15 1e0])
                    
                case 'Half Space'
                    alpha = app.SurfaceSlopeEditField.Value;
                    beta = app.DipAngleEditField.Value;
                    FaultLength = app.FaultLengthkmEditField.Value;
                    z_burial = app.BurialDepthkmEditField.Value;
                    z_fault = z_burial + FaultLength*sin(pi*beta/180);
                    z_upper = -FaultLength*cos(pi*beta/180)*tan(pi*alpha/180);
                    x_max = FaultLength*cos(pi*beta/180);
                    %area(app.GeoAxes, [0,0,x_max], [0,z_fault,z_fault], 'FaceColor', '#BEBEBE')
                    area(app.GeoAxes, [0,x_max], [0,z_upper], z_fault, 'FaceColor', '#BEBEBE')
                    hold(app.GeoAxes, 'on');
                %%% Plot the upper surface.
                    plot(app.GeoAxes, [0; x_max], [0; z_upper], 'k', 'LineWidth', 2)
                %%% Plot the fault surface
                    plot(app.GeoAxes, [0; x_max], [z_burial; z_fault], 'r', 'LineWidth', 2)
                    xlim(app.GeoAxes, [0 x_max])
                    if alpha == 0
                        ylim(app.GeoAxes, [-0.1*z_fault z_fault])
                    else
                        ylim(app.GeoAxes, [z_upper z_fault])
                    end
                    set(app.GeoAxes, 'YDir', 'reverse'); % can maybe just call once during initialization?
                    hold(app.GeoAxes, 'off')

                %%% Set VelAxes limits to speed up drawnow calls.
                    set(app.VelAxes, 'YScale', 'log')
                    xlim(app.VelAxes, [0 x_max])
                    ylim(app.VelAxes, [1e-15 1e0])
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.STOP = 0;            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            SimData = struct('Params', app.p, 'Output', app.Out);
            DataName = app.NameEditField.Value;
            assignin('base', DataName, SimData);
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
        %%% Need to add call to GetParameters, but with a flag that only
        %%% updates the runtime. This is the only parameter that can
        %%% change when restarting.            
            app.STOP = 1;
            app.p.Options.Restart = 1;
            GetParameters(app);
            tic;
        %%% app.Out should already exist, since this is a restart. So
        %%% Stitch the restart Out onto the current app.Out
            Out_R = RunFaultSimUI(app);
            app.Out.NormalStress = [app.Out.NormalStress; Out_R.NormalStress(2:end,:)];
            app.Out.ShearStress = [app.Out.ShearStress; Out_R.ShearStress(2:end,:)];
            app.Out.State = [app.Out.State; Out_R.State(2:end,:)];
            app.Out.Vel = [app.Out.Vel; Out_R.Vel(2:end,:)];
            app.Out.Time = [app.Out.Time; Out_R.Time(2:end,:) + app.Out.Time(end)];
            app.Out.Slip = [app.Out.Slip; Out_R.Slip(2:end,:) + app.Out.Slip(end,:)];
            app.Out.Final = Out_R.Final;
        end

        % Value changed function: dcEditField
        function dcEditFieldValueChanged(app, event)
            % value = app.dcEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            % value = app.bEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
        end

        % Value changed function: ShearModulusGPaEditField
        function ShearModulusGPaEditFieldValueChanged(app, event)
            % value = app.ShearModulusGPaEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
        end

        % Value changed function: PoissonRatioEditField
        function PoissonRatioEditFieldValueChanged(app, event)
            % value = app.PoissonRatioEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
        end

        % Value changed function: NormalStressMPaEditField
        function NormalStressMPaEditFieldValueChanged(app, event)
            % value = app.NormalStressMPaEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
        end

        % Value changed function: FaultLengthkmEditField
        function FaultLengthkmEditFieldValueChanged(app, event)
            % value = app.FaultLengthkmEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
        end

        % Value changed function: SurfaceSlopeEditField
        function SurfaceSlopeEditFieldValueChanged(app, event)
            % value = app.SurfaceSlopeEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
        end

        % Value changed function: DipAngleEditField
        function DipAngleEditFieldValueChanged(app, event)
            % value = app.DipAngleEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
        end

        % Value changed function: BurialDepthkmEditField
        function BurialDepthkmEditFieldValueChanged(app, event)
            % value = app.BurialDepthkmEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
        end

        % Value changed function: GeometryDropDown
        function GeometryDropDownValueChanged(app, event)
            % value = app.GeometryDropDown.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GeoAxes = uiaxes(app.UIFigure);
            xlabel(app.GeoAxes, 'Distance (km)')
            ylabel(app.GeoAxes, 'Depth (km)')
            app.VelAxes = uiaxes(app.UIFigure);
            xlabel(app.VelAxes, 'Distance (km)')
            ylabel(app.VelAxes, 'Slip Speed (m / s)')
            app.GeometryPanel = uipanel(app.UIFigure);
            app.GeometryPanel.Title = 'Geometry';
            app.SurfaceSlopeEditFieldLabel = uilabel(app.GeometryPanel);
            app.SurfaceSlopeEditFieldLabel.Text = 'Surface Slope';
            app.SurfaceSlopeEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.SurfaceSlopeEditField.ValueChangedFcn = createCallbackFcn(app, @SurfaceSlopeEditFieldValueChanged, true);
            app.DipAngleEditFieldLabel = uilabel(app.GeometryPanel);
            app.DipAngleEditFieldLabel.Text = 'Dip Angle';
            app.DipAngleEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.DipAngleEditField.ValueChangedFcn = createCallbackFcn(app, @DipAngleEditFieldValueChanged, true);
            app.FaultLengthkmEditFieldLabel = uilabel(app.GeometryPanel);
            app.FaultLengthkmEditFieldLabel.Text = 'Fault Length [km]';
            app.FaultLengthkmEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.FaultLengthkmEditField.ValueChangedFcn = createCallbackFcn(app, @FaultLengthkmEditFieldValueChanged, true);
            app.BurialDepthkmEditFieldLabel = uilabel(app.GeometryPanel);
            app.BurialDepthkmEditFieldLabel.Text = 'Burial Depth [km]';
            app.BurialDepthkmEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.BurialDepthkmEditField.ValueChangedFcn = createCallbackFcn(app, @BurialDepthkmEditFieldValueChanged, true);
            app.PlotGeometryButton = uibutton(app.GeometryPanel, 'push');
            app.PlotGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGeometryButtonPushed, true);
            app.PlotGeometryButton.Text = 'Plot Geometry';
            app.GeometryDropDownLabel = uilabel(app.GeometryPanel);
            app.GeometryDropDownLabel.Text = 'Geometry';
            app.GeometryDropDown = uidropdown(app.GeometryPanel);
            app.GeometryDropDown.Items = {'Full Space', 'Half Space'};
            app.GeometryDropDown.ValueChangedFcn = createCallbackFcn(app, @GeometryDropDownValueChanged, true);
            app.GeometryDropDown.Value = 'Half Space';
            app.ElasticParametersPanel = uipanel(app.UIFigure);
            app.ElasticParametersPanel.Title = 'Elastic Parameters';
            app.ShearModulusGPaEditFieldLabel = uilabel(app.ElasticParametersPanel);
            app.ShearModulusGPaEditFieldLabel.Text = 'Shear Modulus [GPa]';
            app.ShearModulusGPaEditField = uieditfield(app.ElasticParametersPanel, 'numeric');
            app.ShearModulusGPaEditField.ValueChangedFcn = createCallbackFcn(app, @ShearModulusGPaEditFieldValueChanged, true);
            app.PoissonRatioEditFieldLabel = uilabel(app.ElasticParametersPanel);
            app.PoissonRatioEditFieldLabel.Text = 'Poisson Ratio';
            app.PoissonRatioEditField = uieditfield(app.ElasticParametersPanel, 'numeric');
            app.PoissonRatioEditField.ValueChangedFcn = createCallbackFcn(app, @PoissonRatioEditFieldValueChanged, true);
            app.NormalStressMPaEditFieldLabel = uilabel(app.ElasticParametersPanel);
            app.NormalStressMPaEditFieldLabel.Text = 'Normal Stress [MPa]';
            app.NormalStressMPaEditField = uieditfield(app.ElasticParametersPanel, 'numeric');
            app.NormalStressMPaEditField.ValueChangedFcn = createCallbackFcn(app, @NormalStressMPaEditFieldValueChanged, true);
            app.FrictionParametersPanel = uipanel(app.UIFigure);
            app.FrictionParametersPanel.Title = 'Friction Parameters';
            app.aEditFieldLabel = uilabel(app.FrictionParametersPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.FrictionParametersPanel, 'numeric');
            app.bEditFieldLabel = uilabel(app.FrictionParametersPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.FrictionParametersPanel, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.dcEditFieldLabel = uilabel(app.FrictionParametersPanel);
            app.dcEditFieldLabel.Text = 'dc';
            app.dcEditField = uieditfield(app.FrictionParametersPanel, 'numeric');
            app.dcEditField.ValueChangedFcn = createCallbackFcn(app, @dcEditFieldValueChanged, true);
            app.v0EditFieldLabel = uilabel(app.FrictionParametersPanel);
            app.v0EditFieldLabel.Text = 'v0';
            app.v0EditField = uieditfield(app.FrictionParametersPanel, 'numeric');
            app.mu0EditFieldLabel = uilabel(app.FrictionParametersPanel);
            app.mu0EditFieldLabel.Text = 'mu0';
            app.mu0EditField = uieditfield(app.FrictionParametersPanel, 'numeric');
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.StateLawDropDownLabel = uilabel(app.OptionsPanel);
            app.StateLawDropDownLabel.Text = 'State Law';
            app.StateLawDropDown = uidropdown(app.OptionsPanel);
            app.StateLawDropDown.Items = {'Aging', 'Slip', 'Other'};
            app.StateLawDropDown.Value = 'Slip';
            app.PlotsOnCheckBox = uicheckbox(app.OptionsPanel);
            app.PlotsOnCheckBox.Text = 'Plots On';
            app.PlotsOnCheckBox.Value = true;
            app.RunTimeyrEditFieldLabel = uilabel(app.OptionsPanel);
            app.RunTimeyrEditFieldLabel.Text = 'Run Time [yr]';
            app.RunTimeyrEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.LoadingRatemsEditFieldLabel = uilabel(app.OptionsPanel);
            app.LoadingRatemsEditFieldLabel.Text = {'Loading'; 'Rate [m/s]'};
            app.LoadingRatemsEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.SetPropertiesButton = uibutton(app.ControlsPanel, 'push');
            app.SetPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @SetPropertiesButtonPushed, true);
            app.SetPropertiesButton.Text = 'Set Properties';
            app.GridPointsEditFieldLabel = uilabel(app.ControlsPanel);
            app.GridPointsEditFieldLabel.Text = 'Grid Points';
            app.GridPointsEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.StartButton = uibutton(app.ControlsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ExportButton = uibutton(app.ControlsPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.NameEditFieldLabel = uilabel(app.ControlsPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.ControlsPanel, 'text');
            app.RestartButton = uibutton(app.ControlsPanel, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.hkmLabel = uilabel(app.ControlsPanel);
            app.hkmLabel.Text = 'h* =               [km]';
            app.LbkmLabel = uilabel(app.ControlsPanel);
            app.LbkmLabel.Text = 'Lb =               [km]';
            app.TimeTextAreaLabel = uilabel(app.UIFigure);
            app.TimeTextAreaLabel.Text = 'Time:';
            app.TimeTextArea = uitextarea(app.UIFigure);
            app.ExecutionTimesTextAreaLabel = uilabel(app.UIFigure);
            app.ExecutionTimesTextAreaLabel.Text = {'Execution'; 'Time [s]'};
            app.ExecutionTimesTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = faultZ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end